classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        XButton_2          matlab.ui.control.StateButton
        YButton            matlab.ui.control.StateButton
        YButton_2          matlab.ui.control.StateButton
        ZButton            matlab.ui.control.StateButton
        ZButton_2          matlab.ui.control.StateButton
        MainControlsLabel  matlab.ui.control.Label
        XButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Property % Description
        xpos
        xneg
        ypos
        yneg
        zpos
        zneg
        vx
        vy
        vz
        Kv
        q
        robot
        vectorm
    end
    
    methods (Access = private)
        
        function controlm = read(app)
            % function should be in a loop (keeps running)
            controlm(1) = app.xpos;
            controlm(2) = app.xneg;
            controlm(3) = app.ypos;
            controlm(4) = app.yneg;
            controlm(5) = app.zpos;
            controlm(6) = app.zneg;
            
            % when there is a change -> update calculations and animate
            if controlm ~= zeros(1,6)
                app.updateCalcs
                app.robotAnimate
            end
        end
        
        function vectorm = vectorCalc(app)
            % used in updateCalcs to calculate vectorm
            app.vx = app.Kv*(app.xpos - app.xneg);
            app.vy = app.Kv*(app.ypos - app.yneg);
            app.vz = app.Kv*(app.zpos - app.zneg);
            vectorm = [app.vx app.vy app.vz 0 0 0];
            app.vectorm = vectorm;
        end
        
        function q = qCalc(app)
            % used in updateCalcs to calculate q
            J = app.robot.jacob0(app.q);
            dq = pinv(J)*app.vectorm;
            dt = 0.15;
            q = app.q + dq'*dt;
            app.q = q;
        end
        
        function getRobot(app, robot)
            % run function after declaring the object
            app.robot = robot;
            app.q = app.robot.getpos;
            app.updateCalcs;
        end
        
        function updateCalcs(app)
            app.vectorCalc
            app.qCalc
        end
        
        function robotAnimate(app)
            app.robot.animate(app.q)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xpos = 0;
            app.xneg = 0;
            app.ypos = 0;
            app.yneg = 0;
            app.zpos = 0;
            app.zneg = 0;
            app.vx = 0;
            app.vy = 0;
            app.vz = 0;
            app.Kv = 0.2;
            app.q = zeros(1,6);
            app.vectorm = zeros(1,6);
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.xpos = app.XButton.Value;
        end

        % Value changed function: XButton_2
        function XButton_2ValueChanged(app, event)
            app.xneg = app.XButton_2.Value;
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.ypos = app.YButton.Value;
        end

        % Value changed function: YButton_2
        function YButton_2ValueChanged(app, event)
            app.yneg = app.YButton_2.Value;
        end

        % Value changed function: ZButton
        function ZButtonValueChanged(app, event)
            app.zpos = app.ZButton.Value;
        end

        % Value changed function: ZButton_2
        function ZButton_2ValueChanged(app, event)
            app.zneg = app.ZButton_2.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainControlsLabel = uilabel(app.UIFigure);
            app.MainControlsLabel.Text = 'Main Controls';
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'state');
            app.XButton_2.ValueChangedFcn = createCallbackFcn(app, @XButton_2ValueChanged, true);
            app.XButton_2.Text = 'X-';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'state');
            app.YButton_2.ValueChangedFcn = createCallbackFcn(app, @YButton_2ValueChanged, true);
            app.YButton_2.Text = 'Y-';
            app.ZButton = uibutton(app.UIFigure, 'state');
            app.ZButton.ValueChangedFcn = createCallbackFcn(app, @ZButtonValueChanged, true);
            app.ZButton.Text = 'Z+';
            app.ZButton_2 = uibutton(app.UIFigure, 'state');
            app.ZButton_2.ValueChangedFcn = createCallbackFcn(app, @ZButton_2ValueChanged, true);
            app.ZButton_2.Text = 'Z-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end